classdef ScannerSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScannerSettingsUIFigure   matlab.ui.Figure
        ImageSizeDropDown         matlab.ui.control.DropDown
        ImageSizeDropDownLabel    matlab.ui.control.Label
        ScanBackRightSpinner      matlab.ui.control.Spinner
        ScanBackLeftSpinner       matlab.ui.control.Spinner
        ScanPulsePerPixelSpinner  matlab.ui.control.Spinner
        ScanWaitSpinner           matlab.ui.control.Spinner
        PulsePerPixelLabel        matlab.ui.control.Label
        WaitpulseLabel            matlab.ui.control.Label
        ScanBackEditField_2Label  matlab.ui.control.Label
        ScanSpaceLeftLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp; % Description
    end
    
    methods (Access = public)
        
        function variableInit(app)
            imageSize = app.MainApp.scannerConfig.imageSize;
            pulsePerPixel = app.MainApp.scannerConfig.pulsePerPixel;
            scanWait = app.MainApp.scannerConfig.scanWait;
            scanBackLeftPixelTwice = app.MainApp.scannerConfig.scanBackLeftPixelTwice; % unit: pixel，scanleft和 scan right 也受到 pulsePerPixel 的影响
            scanBackRightPixelTwice = app.MainApp.scannerConfig.scanBackRightPixelTwice; % unit: pixel


            % 设置 imageSize
            switch imageSize
                case 512
                    app.ImageSizeDropDown.Value ='512*512';
                case 256
                    app.ImageSizeDropDown.Value ='256*256';
                otherwise
                    app.ImageSizeDropDown.Value ='512*512';
            end

            % 设置perlse per pixel
            app.ScanPulsePerPixelSpinner.Value = pulsePerPixel;

            % 设置 scanWait
            app.ScanWaitSpinner.Value = scanWait;

            % 设置 scanBackLeftPixelTwice
            app.ScanBackLeftSpinner.Value = scanBackLeftPixelTwice;

            % 设置 scanBackRightPixelTwice
            app.ScanBackRightSpinner.Value = scanBackRightPixelTwice;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;
            app.ScannerSettingsUIFigure.Position = [app.MainApp.RoiImagingModuleUIFigure.Position(1)+app.MainApp.RoiImagingModuleUIFigure.Position(3)+5,...
                app.MainApp.RoiImagingModuleUIFigure.Position(2),...
                342,...         
                 260];

            % 获取Mainapp的Scanner Settings,并修改Subapp的选项显示
            app.variableInit();

        end

        % Value changed function: ImageSizeDropDown
        function ImageSizeDropDownValueChanged(app, event)
            value = app.ImageSizeDropDown.Value;
            switch value
                case '512*512'
                    app.MainApp.scannerConfig.imageSize = 512;
                case '256*256'
                    app.MainApp.scannerConfig.imageSize = 256;
            end
        end

        % Value changed function: ScanPulsePerPixelSpinner
        function ScanPulsePerPixelSpinnerValueChanged(app, event)
            value = app.ScanPulsePerPixelSpinner.Value;
            app.MainApp.scannerConfig.pulsePerPixel  = value;
        end

        % Value changed function: ScanWaitSpinner
        function ScanWaitSpinnerValueChanged(app, event)
            value = app.ScanWaitSpinner.Value;
            app.MainApp.scannerConfig.scanWait = value;
        end

        % Value changed function: ScanBackLeftSpinner
        function ScanBackLeftSpinnerValueChanged(app, event)
            value = app.ScanBackLeftSpinner.Value;
            app.MainApp.scannerConfig.scanBackLeftPixelTwice = value;
        end

        % Value changed function: ScanBackRightSpinner
        function ScanBackRightSpinnerValueChanged(app, event)
            value = app.ScanBackRightSpinner.Value;
            app.MainApp.scannerConfig.scanBackRightPixelTwice = value;
        end

        % Close request function: ScannerSettingsUIFigure
        function ScannerSettingsUIFigureCloseRequest(app, event)
            app.MainApp.ScannerSettingsApp = 0;

            try
                delete(app);
            catch
                disp('error');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScannerSettingsUIFigure = uifigure('Visible', 'off');
            app.ScannerSettingsUIFigure.Name = 'Scanner Settings';
            app.ScannerSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ScannerSettingsUIFigureCloseRequest, true);
            app.ScanSpaceLeftLabel = uilabel(app.ScannerSettingsUIFigure);
            app.ScanSpaceLeftLabel.Text = 'Scan Left*2 (pixel)';
            app.ScanBackEditField_2Label = uilabel(app.ScannerSettingsUIFigure);
            app.ScanBackEditField_2Label.Text = 'Scan Right*2 (pixel)';
            app.WaitpulseLabel = uilabel(app.ScannerSettingsUIFigure);
            app.WaitpulseLabel.Text = 'Wait (pulse)';
            app.PulsePerPixelLabel = uilabel(app.ScannerSettingsUIFigure);
            app.PulsePerPixelLabel.Text = 'Pulse Per Pixel';
            app.ScanWaitSpinner = uispinner(app.ScannerSettingsUIFigure);
            app.ScanWaitSpinner.Limits = [0 Inf];
            app.ScanWaitSpinner.ValueDisplayFormat = '%.0f';
            app.ScanWaitSpinner.ValueChangedFcn = createCallbackFcn(app, @ScanWaitSpinnerValueChanged, true);
            app.ScanWaitSpinner.Value = 56;
            app.ScanPulsePerPixelSpinner = uispinner(app.ScannerSettingsUIFigure);
            app.ScanPulsePerPixelSpinner.Limits = [1 Inf];
            app.ScanPulsePerPixelSpinner.ValueDisplayFormat = '%.0f';
            app.ScanPulsePerPixelSpinner.ValueChangedFcn = createCallbackFcn(app, @ScanPulsePerPixelSpinnerValueChanged, true);
            app.ScanPulsePerPixelSpinner.Value = 1;
            app.ScanBackLeftSpinner = uispinner(app.ScannerSettingsUIFigure);
            app.ScanBackLeftSpinner.Limits = [0 Inf];
            app.ScanBackLeftSpinner.ValueDisplayFormat = '%.0f';
            app.ScanBackLeftSpinner.ValueChangedFcn = createCallbackFcn(app, @ScanBackLeftSpinnerValueChanged, true);
            app.ScanBackLeftSpinner.Value = 55;
            app.ScanBackRightSpinner = uispinner(app.ScannerSettingsUIFigure);
            app.ScanBackRightSpinner.Limits = [0 Inf];
            app.ScanBackRightSpinner.ValueDisplayFormat = '%.0f';
            app.ScanBackRightSpinner.ValueChangedFcn = createCallbackFcn(app, @ScanBackRightSpinnerValueChanged, true);
            app.ScanBackRightSpinner.Value = 55;
            app.ImageSizeDropDownLabel = uilabel(app.ScannerSettingsUIFigure);
            app.ImageSizeDropDownLabel.Text = 'Image Size';
            app.ImageSizeDropDown = uidropdown(app.ScannerSettingsUIFigure);
            app.ImageSizeDropDown.Items = {'512*512', '256*256'};
            app.ImageSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageSizeDropDownValueChanged, true);
            app.ImageSizeDropDown.Value = '512*512';
            % Show the figure after all components are created
            app.ScannerSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScannerSettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ScannerSettingsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ScannerSettingsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScannerSettingsUIFigure)
        end
    end
end