classdef RoiImagingModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RoiImagingModuleUIFigure     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        SaveConfigMenu               matlab.ui.container.Menu
        SettingsMenu                 matlab.ui.container.Menu
        AWGSettingsMenu              matlab.ui.container.Menu
        ScannerSettingsMenu          matlab.ui.container.Menu
        AddonsMenu                   matlab.ui.container.Menu
        PowerCaculateMenu            matlab.ui.container.Menu
        AWGControlMenu               matlab.ui.container.Menu
        ROIImagingSimulationMenu     matlab.ui.container.Menu
        CustomDrawMenu_2             matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        Toolbar                      matlab.ui.container.Toolbar
        SimulationToggleTool         matlab.ui.container.toolbar.ToggleTool
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        SettingsPanel                matlab.ui.container.Panel
        ScanimageButton              matlab.ui.control.StateButton
        ConfigurationFileEditField   matlab.ui.control.EditField
        ConfigurationEditFieldLabel  matlab.ui.control.Label
        AdvancedSettingsOpenButton   matlab.ui.control.Button
        ConfigFileSelectButton       matlab.ui.control.Button
        isConnectedLabel             matlab.ui.control.Label
        AWGStatusLabel               matlab.ui.control.Label
        AwgConnectButton             matlab.ui.control.Button
        ROIImagingPanel              matlab.ui.container.Panel
        ROIImagingLamp               matlab.ui.control.Lamp
        AbortButton                  matlab.ui.control.Button
        ROIImagingButton             matlab.ui.control.Button
        ManualcorrectionPanel        matlab.ui.container.Panel
        LoadMaskButton               matlab.ui.control.Button
        ROIdilateSpinner             matlab.ui.control.Spinner
        ROIdilateSpinnerLabel        matlab.ui.control.Label
        MaskOnCheckBox               matlab.ui.control.CheckBox
        MaskDropDown                 matlab.ui.control.DropDown
        MaskDropDownLabel            matlab.ui.control.Label
        NeuronSegmentationPanel      matlab.ui.container.Panel
        LoadImageButton_2            matlab.ui.control.Button
        RunModelButton               matlab.ui.control.Button
        ThresholdSpinner             matlab.ui.control.Spinner
        ThresholdSpinnerLabel        matlab.ui.control.Label
        ModelsDropDown               matlab.ui.control.DropDown
        ModelsDropDownLabel          matlab.ui.control.Label
        StructureImagingButtonGroup  matlab.ui.container.ButtonGroup
        StructureImagingLamp         matlab.ui.control.Lamp
        AWGOutputButton              matlab.ui.control.Button
        LoadImageButton              matlab.ui.control.Button
        oneOnNineOffButton           matlab.ui.control.RadioButton
        KeeponButton                 matlab.ui.control.RadioButton
        RightPanel                   matlab.ui.container.Panel
        ROIRatioEditField_2          matlab.ui.control.NumericEditField
        ROIRatioEditField_2Label     matlab.ui.control.Label
        ROIsEditField                matlab.ui.control.NumericEditField
        ROIsEditFieldLabel           matlab.ui.control.Label
        PowerCaculatePanel           matlab.ui.container.Panel
        UpdateButton                 matlab.ui.control.Button
        PowerCostEditField           matlab.ui.control.NumericEditField
        PowerCoseLabel               matlab.ui.control.Label
        ROIPowerEditField            matlab.ui.control.NumericEditField
        ROIPowermWEditField_2Label   matlab.ui.control.Label
        ROIRatioEditField            matlab.ui.control.NumericEditField
        ROIRatioEditFieldLabel       matlab.ui.control.Label
        StructurePowerEditField      matlab.ui.control.NumericEditField
        MHzPowermWEditFieldLabel     matlab.ui.control.Label
        ImagingPowerEditField        matlab.ui.control.NumericEditField
        LaserPowermWLabel            matlab.ui.control.Label
        ChannelDropDown              matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        % Sub APP
        AwgSettingsApp = 0;% sub-app for awg settings
        ScannerSettingsApp = 0; % sub-app for scanner settings
        AwgControlApp;
        SimulationApp;
        PowerCaculateAPP;
        awgDevice= ividev.NIFGEN.empty; % AWG Device Object, default:empty
        roiMask % Dilate之后
        roiMaskRaw; % 原图
        imgAvgCh1;% Description
        imgAvgCh2;
        listeners;
        hSI = scanimage.SI.empty;
        hSICtl = scanimage.SIController.empty;

        % components
        structureRebuild = components.scanimageRealtimeRebuildAvg.empty;
        DrawROI = components.DrawROI.empty; % Description;
        Segmentation; 
    end


    % path and folder
    properties (Access = private)
        folder; % app folder
        lastStructureImagepath = '';  % save last selected path of structure
        lastRoiMaskPath= ''; % save last selected path of roi mask
        lastConfigPath = ''; % save last selected path of config file
        last_tiff_path = ''; % save last selected path of avg tif file

        image_showing; % Description
    end

    % listener
    properties (Access = private)
        structureListener = event.listener.empty;% listener for structure imaging
        powerListener  = event.listener.empty;
    end
    
    % AWG Settings,Constant =true 让外部可以直接访问
    properties (Access = public)

        defaultConfig = struct();
        waveformConfig = struct();
        scannerConfig = struct();
        draw;
        segmentation;
        imshow_handles;
    end


    methods (Access = private)
        
        function create_structure_pulse(app)
            % create pulse for structure imaging
            imageSize = app.scannerConfig.imageSize;

            % generate ROI pulse for each frame
            % 循环10次，创建waveformHandlesArray
            n = 10;
            waveformHandlesArray = cell(1,n);

            colNum = floor(imageSize/n);
            for i=1:n
                % 创建一个黑色的图像矩阵
                temp_mask = repmat(app.defaultConfig.pulseOff,imageSize, imageSize);

                % 计算本次要填充的列
                start = (i-1)*colNum+1;

                % 将这些列填充为白色
                if i == 10
                    % 如果是最后一列，则最后一次填充剩余的所有列
                    temp_mask(:, start:end) = app.defaultConfig.pulseOn;
                else
                    % 如果是前9次，则一次填充colNum列
                    temp_mask(:, start:start+colNum-1) = app.defaultConfig.pulseOn;
                end


                framePulse = create_frame_roi_pulse(app,temp_mask);

                % create waveform
                waveformHandle = awg.create_waveform_handle(app.awgDevice,framePulse);

                % add to waveformHandlesArray
                waveformHandlesArray{i} = waveformHandle;
            end
            waveformHandlesArray = cell2mat(waveformHandlesArray); % matlab 循环用cell 存储，然后再转化为mat矩阵

            sequenceLength = n; % seq 数
            loopCountsArray = ones(1,n); % 设置每个waveform的输出
            waveformSize = numel(framePulse);
            sampleCountsArray = repmat(waveformSize,1,n); % 每个waveform长度
            markerLocationArray = repmat(-1,1,n); % 是否输出markerLocation

            configureOutputMode(app.awgDevice,"OUTPUT_SEQ");
            [~, sequenceHandle] = createAdvancedArbSequence(app.awgDevice, sequenceLength, waveformHandlesArray, loopCountsArray, sampleCountsArray, markerLocationArray); % 创建sequenceHandle

            % generate Arb waveform
            awg.create_arb_sequence(app.awgDevice,sequenceHandle,app.waveformConfig);
        end

        function framePulse = create_frame_roi_pulse(app,roiMask)
            
            % Read parameters
            pulsePerPixel = app.scannerConfig.pulsePerPixel;
            scanBackLeftPixelTwice = app.scannerConfig.scanBackLeftPixelTwice * pulsePerPixel; % unit: pixel，scanleft和 scan right 也受到 pulsePerPixel 的影响
            scanBackRightPixelTwice = app.scannerConfig.scanBackRightPixelTwice * pulsePerPixel; % unit: pixel
            imageSize = app.scannerConfig.imageSize;
            scanWait = app.scannerConfig.scanWait;
            
            
            % Generate ROI mask
            roiMaskPulse = repelem(roiMask, 1, pulsePerPixel); % Each pixel has several pulses, so the signal needs to be repeated
            roiMaskPulse(2:2:end, :) = fliplr(roiMaskPulse(2:2:end, :)); % Because of bidirectional scanning, the laser is scanned from left to right in the first line, and the second line is directly scanned from right, so even rows need to be mirrored
            
            % Add scan right to odd rows
            oddLineSignal = [roiMaskPulse(1:2:end, :) repmat(app.defaultConfig.pulseOff, imageSize / 2, scanBackRightPixelTwice)];
            
            % Add scan left to even rows
            evenLineSignal = [roiMaskPulse(2:2:end, :) repmat(app.defaultConfig.pulseOff, imageSize / 2, scanBackLeftPixelTwice)];
            
            % Then the odd row matrix and the even row matrix are pasted together in sequence and reduced to 1*n (considering the impact of changing the length of the array dynamically in the loop on performance, so use this method to optimize)
            signalPulse = reshape([oddLineSignal evenLineSignal]', 1, []);
            
            % Compose the final framePusle
            waitPulse = repmat(app.defaultConfig.pulseOff, 1, scanWait); % Add wait time in front
            scanleftFirstPulse = repmat(app.defaultConfig.pulseOff, 1, round(scanBackLeftPixelTwice / 2));
            framePulse = [waitPulse, scanleftFirstPulse, signalPulse(1:end - round(scanBackLeftPixelTwice / 2))]; % The first scanleft is missing, the last scanleft is extra
            
            % Actually, do not need to consider Flyback, but the length of the waveform must be an integer multiple of 4, so it needs to be supplemented
            if mod(length(framePulse), 4) ~= 0
                extraNum = 4 - mod(length(framePulse), 4);
                framePulse = [framePulse, repmat(app.defaultConfig.pulseOff, 1, extraNum)];
            end
        end
        
        function load_config(app,path)
            % read file
            fid = fopen(path, 'r');
            jsonStr = fscanf(fid, '%c');
            fclose(fid);
            % json to struct
            userConfig  = jsondecode(jsonStr);
            % assignment value
            app.defaultConfig = userConfig.defaultConfig;
            app.waveformConfig = userConfig.waveformConfig;
            app.scannerConfig = userConfig.scannerConfig;
        end
        
        function init_default(app)
            % get app folder
            fullpath = mfilename('fullpath');
            [path,~]=fileparts(fullpath);
            app.folder = path;
            % defaultConfig
            app.defaultConfig.pulseOn = 0;
            app.defaultConfig.pulseOff = 1;
            app.defaultConfig.configPath = './config/';
            app.defaultConfig.roimaskPath = './roi_mask/';
            % waveformConfig
            app.waveformConfig.resourceID = "Dev1"; % AWG Var: resourceID
            app.waveformConfig.gain = 3.3;
            app.waveformConfig.offset = 0;
            app.waveformConfig.sampleRate = 1.0007e6; % 1.0007 MHz
            app.waveformConfig.delayTime = 0;
            app.waveformConfig.mode = 'Stepped'; % Stepped
            app.waveformConfig.clockSource = 'ClkIn'; % 'OnboardClock' or 'ClkIn'
            app.waveformConfig.triggerOn = true; % true or false
            app.waveformConfig.triggerPort = 'PFI0'; % PFI0 or PFI1
            app.waveformConfig.exportSignalOn = true; % true or false
            app.waveformConfig.exportSignalType = 'DataMarker'; % Marker DataMarker
            app.waveformConfig.exportSignalPort = 'PFI1'; % PFI0 or PFI1

            % scannerConfig
            app.scannerConfig.imageSize = 512;
            app.scannerConfig.pulsePerPixel = 1;
            app.scannerConfig.scanWait = 56;
            app.scannerConfig.scanBackLeftPixelTwice = 40; % unit: pixel，scanleft和 scan right 也受到 pulsePerPixel 的影响
            app.scannerConfig.scanBackRightPixelTwice = 40; % unit: pixel
        end
        
        function process_structure_image(app,filename,path)
                % 分割tif为两个通道
                fullpath = fullfile(path, filename);
                [imgStackCh1,imgStackCh2] = utils.tiff_read_volume(fullpath,'nChannel',2);
                tagstruct = utils.tiff_info(fullpath);
                
                % 需要去ripple noise
                rippleNoise = 700;
                imgStackCh1(imgStackCh1<rippleNoise) = 0;
                imgStackCh2(imgStackCh2<rippleNoise) = 0;

                % 每十张组合成一张图
                imgStackCombineCh1 =  utils.tiff_extract(imgStackCh1);
                imgStackCombineCh2 =  utils.tiff_extract(imgStackCh2);

                frames = size(imgStackCombineCh1,3);
                
                % 创建Processed文件夹
                folderProcessed = fullfile(path,'Stucture'); % TODO：这个也作为配置文件？
                if ~exist(folderProcessed, 'dir')
                    mkdir(folderProcessed)
                end
                
                % 保存为raw tif
                [~, name, ext] = fileparts(filename);
                utils.tiff_save(fullfile(folderProcessed, [name,'_ch1',ext]),imgStackCombineCh1,tagstruct);
                utils.tiff_save(fullfile(folderProcessed, [name,'_ch2',ext]),imgStackCombineCh2,tagstruct);

                % 保存平均图
                app.imgAvgCh1 = utils.tiff_save_avg(fullfile(folderProcessed, [name,'_ch1_AVG',ext]),imgStackCombineCh1);
                app.imgAvgCh2 = utils.tiff_save_avg(fullfile(folderProcessed, [name,'_ch2_AVG',ext]),imgStackCombineCh2);
                
                % 自动调整对比度 EnhanceContrast
                utils.tiff_adjust(fullfile(folderProcessed, sprintf('%s_ch1_%d_Frames_AVG_EnhanceContrast%s', name,frames, ext)),app.imgAvgCh1);
                utils.tiff_adjust(fullfile(folderProcessed, sprintf('%s_ch2_%d_Frames_AVG_EnhanceContrast%s', name,frames, ext)),app.imgAvgCh2);

                %% 保存结构图
                % 保存max图
                utils.tiff_save_max(fullfile(folderProcessed,[name,'_ch1_MAX_AVG',ext]),imgStackCombineCh1,0.05);
                utils.tiff_save_max(fullfile(folderProcessed, [name,'_ch2_MAX_AVG',ext]),imgStackCombineCh2,0.05);       
                
                % 保存std图
                utils.tiff_save_std(fullfile(folderProcessed,[name,'_ch1_STD',ext]),imgStackCombineCh1);
                utils.tiff_save_std(fullfile(folderProcessed, [name,'_ch2_STD',ext]),imgStackCombineCh2);
                % 加载imgAvgCh1的图像
                app.ChannelDropDown.Enable = "on";
                tempImg=rescale(app.imgAvgCh1); %将im归一化到[0 1]区间内，且im中的最大值和最小值赋值为1和0
                tempImg=im2uint8(tempImg); %将im扩充到[0 255]
                imshow(tempImg,'parent',app.UIAxes,'border','tight','initialmagnification','fit');
                axis(app.UIAxes, 'tight');
        end
        
        function load_roi_mask(app,path,filename)
            % load mask file
            if endsWith(filename,'.csv') || endsWith(filename,'.txt') 
                app.roiMaskRaw = table2array(readtable([path,filename]));
            elseif endsWith(filename,'.png') 
                imMask = imread([path,filename]);
                dims = ndims(imMask);
                if dims == 2
                    %如果导入的图片是黑白图
                    imMask(imMask~=0) = 1;
                    app.roiMaskRaw = single(imMask);

                else
                    %如果导入的图片是rgb图
                    app.roiMaskRaw = rgb2gray(imMask);
                end
            end
            % dialte mask
            SE = strel('disk',app.ROIdilateSpinner.Value);
            app.roiMask = imdilate(app.roiMaskRaw ,SE);


            % display mask
            imshow(app.roiMask,'parent',app.UIAxes,'border','tight','initialmagnification','fit');
            axis(app.UIAxes, 'tight');


            % caculate ROI ratio
            roiRatio = round(length(find(app.roiMask>0))/numel(app.roiMask),4);
            app.ROIRatioEditField_2.Value = roiRatio;
            app.ROIRatioEditField.Value = roiRatio;
            % caculate ROI Power
            app.caculate_power();
            % active low logic: 1（white) to 0, 0（black）to 1
            app.roiMask = utils.active_low_logic(app.roiMask);
        end
        
        function caculate_power(app)
            % 计算理想激光功率，方便调整
            % get value
            imagingPower= app.ImagingPowerEditField.Value;
            powerCost = app.PowerCostEditField.Value;
            roiRatio = app.ROIRatioEditField.Value;
            scanArea = (app.scannerConfig.scanBackLeftPixelTwice/2+app.scannerConfig.imageSize+app.scannerConfig.scanBackRightPixelTwice/2)*app.scannerConfig.imageSize+app.scannerConfig.scanWait;
            acquisitionArea = app.scannerConfig.imageSize*app.scannerConfig.imageSize;
            fillfraction = acquisitionArea/scanArea;

            % caculate power
            %structurePower = imagingPower *0.1* powerCost*fillfraction;
            structurePower = imagingPower *0.1;
            roiPower = imagingPower*roiRatio*fillfraction*powerCost;
            
            % update value in gui
            app.StructurePowerEditField.Value = structurePower;
            app.ROIPowerEditField.Value = roiPower;
        end


        function structure_imaging_callback(app,~,~)
            % listen to scanimage event
            % reset device for new pusle
            reset(app.awgDevice);
            
            % create structure pusle
            app.create_structure_pulse();
            disp("ROI Imaging Module: 0.1MHz Imaging");
            
            % 0.1MHZ: light lamp
            app.StructureImagingLamp.Color = [0.00,1.00,0.00];


        end

        function laser_keep_on(app)
            reset(app.awgDevice);
            % create Arb waveform
            waveformDataArray = repmat(app.defaultConfig.pulseOn,1,20);
            waveformHandle = awg.create_waveform_handle(app.awgDevice,waveformDataArray);

            % generate Arb waveform with no trigger(do not wait until scanimage grab a image)
            awg.create_arb_waveform_notrigger(app.awgDevice,waveformHandle,app.waveformConfig);

            % turn on nogating lamp 
            app.StructureImagingLamp.Color = [0.90,0.90,0.90];
            pause(0.1);
            app.StructureImagingLamp.Color = [0.93,0.69,0.13];
            app.ROIImagingLamp.Color = [0.90,0.90,0.90];
            disp("ROI Imaging Module: Laser Keep On");
            
            % scanimage
            delete(app.structureRebuild);
            if isvalid(app.hSI)
%                 app.hSI.hChannels.channelLUT{1} = [700,2400]; % [black,white]
%                 app.hSI.hChannels.channelLUT{2} = [700,2400];
%                 app.hSI.hScan2D.pixelBinFactor = 1;
            end

        end

        function laser_1on_9off(app)
            reset(app.awgDevice);
            % create pulse for keeping laser off
            modulatedArray = [app.defaultConfig.pulseOn,repmat(app.defaultConfig.pulseOff,1,9)];
            waveformDataArray = repmat(modulatedArray,1,2);
            waveformHandle = awg.create_waveform_handle(app.awgDevice,waveformDataArray);

            % generate Arb waveform with no trigger(do not wait until scanimage grab a image)
            awg.create_arb_waveform_notrigger(app.awgDevice,waveformHandle,app.waveformConfig);
            
            % turn on structure lamp 
            app.StructureImagingLamp.Color = [0.90,0.90,0.90];
            pause(0.1);
            app.StructureImagingLamp.Color = [0.00,1.00,0.00];
            app.ROIImagingLamp.Color = [0.90,0.90,0.90];
            disp("ROI Imaging Module: 0.1MHz Imaging");
            
            % TODO, 这个可以设置为配置选项，比如在config.json文件里设置两个
            % scanimage 
            if isvalid(app.hSI)
                app.hSI.hChannels.channelLUT{1} = [200,400]; % [black,white]
                app.hSI.hChannels.channelLUT{2} = [200,400];
                app.hSI.hScan2D.pixelBinFactor = 10;
            end
        end
        
        
        function laser_keep_off(app)
            reset(app.awgDevice);
            % create pulse for keeping laser off
            waveformDataArray = repmat(app.defaultConfig.pulseOff,1,20);
            waveformHandle = awg.create_waveform_handle(app.awgDevice,waveformDataArray);

            % generate Arb waveform with no trigger(do not wait until scanimage grab a image)
            awg.create_arb_waveform_notrigger(app.awgDevice,waveformHandle,app.waveformConfig);

            % turn off all lamps 
            app.StructureImagingLamp.Color = [0.90,0.90,0.90];
            app.ROIImagingLamp.Color = [0.90,0.90,0.90];
        end
        
        function get_scanimage_power(app)
            lut = app.hSI.hBeams.hBeams{1, 1}.powerFraction2PowerWattLut;
            fraction = app.hSI.hBeams.powerFractions;
            power_W = utils.interp1_extended(lut(:,1),lut(:,2),fraction,'linear','extrap'); % unit: W
            app.ImagingPowerEditField.Value = round(power_W*10^3);
        end
        
        function update_scanimage_power(app,~,~)
            disp("update power")
            % get scanimage power fraction and caculate the laser power by
            % lut
            app.get_scanimage_power();
            % caculate 0.1MHz and ROI actual power
            app.caculate_power();
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % init parameter
            assignin("base",'app',app);
            app.init_default();
            
            app.RoiImagingModuleUIFigure.UserData.CtrlPressed = false;
            app.DrawROI = components.DrawROI(app);

            
            app.draw.status = "idle"; % idle,handroi_drawing,paning
            app.draw.pan.last_position = [];

            app.segmentation.seg_mask = [];
            app.Segmentation.seg_flow = [];            
            %app.RoiImagingModuleUIFigure.Position = [40 100 899 661];
            %axis(app.UIAxes,'off'); 
            % zoomObj = zoom(app.UIAxes,'on'); 
            %axtoolbar(app.UIAxes,{'pan' , 'zoomin' ,  'restoreview' });


            % add python environment
            if count(py.sys.path,'+components') == 0
                insert(py.sys.path,int32(0),'+components/+segmentation');
            end
            % ui
            app.ScanimageButton.BackgroundColor = [1.00,0.00,0.00];
            % init config settings

            default_json = fullfile(app.folder,app.defaultConfig.configPath,'default.json');
            if exist(default_json,'file') ~= 0
                % if default.json exist
                app.load_config(default_json);
                app.ConfigurationFileEditField.Value = 'default.json';
            end

            % test ：scanimage 联动
%             if isvalid(app.structureListener)
%                 delete(app.structureListener);
%             end
            

            try
                app.hSI = evalin('base', 'hSI');
                app.hSICtl = evalin('base', 'hSICtl');
            catch
                uialert(app.RoiImagingModuleUIFigure,"Please Start Scanimage First",'Warning','Icon','warning');
                return
            end
            app.ScanimageButton.BackgroundColor = [0.33,0.60,0.85];


        end

        % Button pushed function: ConfigFileSelectButton
        function ConfigFileSelectButtonPushed(app, event)
            % select file
            if isempty(app.lastConfigPath)
                % 考虑当前文件夹可能不是app文件夹
                configPath = fullfile(app.folder,app.defaultConfig.configPath);
                [filename,path]= utils.select_file({'*.json'},configPath);
            else
               [filename,path]= utils.select_file({'*.json'}, app.lastConfigPath);
            end
            
            if filename ~= 0 
                fullpath = [path,filename];
                
                % save path for next click
                app.lastConfigPath = path;

                % load conig
                app.load_config(fullpath)
                
                % show config file in GUI 
                app.ConfigurationFileEditField.Value = filename;

                % log in command line
                disp(['Loaded Config：',fullpath]);


                % if subapp is open
                if app.AwgSettingsApp ~= 0
                    app.AwgSettingsApp.variableInit();
                end
                if app.ScannerSettingsApp ~= 0
                    app.ScannerSettingsApp.variableInit();
                end
            end


        end

        % Button pushed function: AdvancedSettingsOpenButton
        function AdvancedSettingsOpenButtonPushed(app, event)
            app.AwgSettingsApp = subapps.AwgSettings(app);
            app.ScannerSettingsApp = subapps.ScannerSettings(app);
        end

        % Close request function: RoiImagingModuleUIFigure
        function RoiImagingModuleUIFigureCloseRequest(app, event)
            % when exit MainApp
            % disconnect awg device
            delete(app.structureRebuild);

            if isvalid(app.awgDevice)
                reset(app.awgDevice);
                app.laser_keep_on();
                pause(2);
                awg.disconnect(app.awgDevice);
            end
            if isvalid(app.structureListener)
                delete(app.structureListener);
            end
            if isvalid(app.powerListener)
                delete(app.powerListener);
            end
            % close subapp
            if app.AwgSettingsApp ~= 0 
                delete(app.AwgSettingsApp)
            end
            if app.ScannerSettingsApp ~= 0
                delete(app.ScannerSettingsApp)
            end
            
 
            % close MainApp
            delete(app)
        end

        % Button pushed function: AwgConnectButton
        function AwgConnectButtonPushed(app, event)
            % If not connected, click to connect
            if app.isConnectedLabel.Text == "Disconnected"
                % simulated mode on or off
                simulateState = app.SimulationToggleTool.State;
                
                % create progress dialog
                d = uiprogressdlg(app.RoiImagingModuleUIFigure,'Title','Connecting AWG',...
                    'Indeterminate','on');
                drawnow

                % connect to awg
                [app.awgDevice,status] = awg.connect(app.waveformConfig.resourceID,simulateState);

                % close the dialog box
                close(d);

                % if connected to AWG
                if status
                    app.isConnectedLabel.Text = 'Connected';
                    app.AwgConnectButton.Text = 'Disconnect';
                    % laser pusle gating automatically
                    app.laser_keep_on();
                end


            else
                % % If connected, click to disconnect
                reset(app.awgDevice);
                app.laser_keep_on();
                awg.disconnect(app.awgDevice);
                app.isConnectedLabel.Text = 'Disconnected';
                app.AwgConnectButton.Text = 'Connect';

                % turn off all lamps
                app.StructureImagingLamp.Color = [0.90,0.90,0.90];
                app.ROIImagingLamp.Color = [0.90,0.90,0.90];
                app.structureListener = addlistener(app.hSI.hUserFunctions, 'acqModeDone', @app.structure_imaging_callback);
            end
        end

        % Button pushed function: AWGOutputButton
        function AWGOutputButtonPushed(app, event)
            % 选择0.1MHz成像
            if app.KeeponButton.Value
                app.laser_keep_on();
                disp("ROI Imaging Module: Laser is Keep On!");

                % Delete 0.1 MHz Listener to SCANIMAGE for Laser Keep On!
                if isvalid(app.structureListener)
                    delete(app.structureListener);
                end

            % 选择0.1MHz成像
            else
                % 控制AWG进行十分之一成像
                app.structure_imaging_callback();

                % 实时成像重建
                delete(app.structureRebuild);
                
                % 根据scanimage当前打开的channel进行重建？
                app.structureRebuild= components.scanimageRealtimeRebuildAvg(app.hSI);
                app.structureRebuild.listen_to_scanimage();
                
                if isa(app.hSI,'scanimage.SI')
                    if isvalid(app.structureListener)
                        delete(app.structureListener);
                    end
                    app.structureListener = addlistener(app.hSI.hUserFunctions, 'acqAbort', @app.structure_imaging_callback); % focus或grab结束后，自动重置结构成像
                    disp("ROI Imaging Module: Listener to SCANIMAGE for 0.1MHz");
                else
                    warning("Please Start Scanimage First");
                end
            end

        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)

            app.laser_keep_off();
            %abortGeneration(app.awgDevice);
            app.StructureImagingLamp.Color = [0.90,0.90,0.90];
            app.ROIImagingLamp.Color = [0.90,0.90,0.90];
        end

        % Button pushed function: ROIImagingButton
        function ROIImagingButtonPushed(app, event)
            % delete 0.1MHz listener
            delete(app.structureRebuild);
            if isvalid(app.structureListener)
                delete(app.structureListener);
                disp("ROI Imaging Module: Delete 0.1 MHz Listener to SCANIMAGE for ROI imaging!");
            end
            
            % 如果AWG未连接，return
            if isempty(app.awgDevice)
                disp("AWG 未连接")
                return
            end

            % reset AWG device for new waveform            
            reset(app.awgDevice);

            % generate ROI pulse for each frame
            framePulse = app.create_frame_roi_pulse(app.roiMask);

            % create waveform
            waveformHandle = awg.create_waveform_handle(app.awgDevice,framePulse);

            % generate Arb waveform
            awg.create_arb_waveform(app.awgDevice,waveformHandle,app.waveformConfig);
            
            % scanimage contrast
            if isvalid(app.hSI)
                app.hSI.hChannels.channelLUT{1} = [700,2400]; % [black,white]
                app.hSI.hChannels.channelLUT{2} = [700,2400];
                app.hSI.hScan2D.pixelBinFactor = 1;
            end


            % light lamp
            app.StructureImagingLamp.Color = [0.90,0.90,0.90];
            % 闪一下
            app.ROIImagingLamp.Color = [0.90,0.90,0.90];
            pause(0.1);
            app.ROIImagingLamp.Color = [0.07,0.62,1.00];
            disp("ROI Imaging Module: ROI Imaging");
        end

        % Button pushed function: LoadMaskButton
        function LoadMaskButtonPushed(app, event)
            
            % load mask
            if isempty(app.lastRoiMaskPath)
                % 考虑当前文件夹可能不是app文件夹
                roimaskPath = fullfile(app.folder,app.defaultConfig.roimaskPath);
                [filename,path] = utils.select_file({'*.png';'*.csv';'*.txt'},roimaskPath);
            else
                
                [filename,path] = utils.select_file({'*.png';'*.csv';'*.txt'},app.lastRoiMaskPath);
            end
            if filename ~= 0 % 如果不选择文件返回为0
                % save path for next click
                app.lastRoiMaskPath = path;
                
                % create progress dialog
                d = uiprogressdlg(app.RoiImagingModuleUIFigure,'Title','Loading ROI Mask',...
                    'Indeterminate','on');
                drawnow

                % load roi
                app.load_roi_mask(path,filename);

                % close the dialog box
                close(d);
            end
            
            % update ui
            app.ChannelDropDown.Enable = "off";

            
            % 交互完善，加载mask自动进行ROI masking
            app.ROIImagingButtonPushed();

        end

        % Menu selected function: AWGSettingsMenu
        function AWGSettingsMenuSelected(app, event)
            app.AwgSettingsApp = subapps.AwgSettings(app);
        end

        % Menu selected function: ScannerSettingsMenu
        function ScannerSettingsMenuSelected(app, event)
            app.ScannerSettingsApp = subapps.ScannerSettings(app);
        end

        % Menu selected function: AWGControlMenu
        function AWGControlMenuSelected(app, event)
            app.AwgControlApp = addons.AWG_Control_2022b();
            
        end

        % Menu selected function: ROIImagingSimulationMenu
        function ROIImagingSimulationMenuSelected(app, event)
            app.SimulationApp = addons.simulation_veritical_stripe();
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
            
        end

        % Menu selected function: SaveConfigMenu
        function SaveConfigMenuSelected(app, event)
            
            userConfig.defaultConfig = app.defaultConfig;
            userConfig.waveformConfig = app.waveformConfig;
            userConfig.scannerConfig = app.scannerConfig;


            if isempty(app.lastConfigPath)
                [filename,path] =  utils.save_file({'*.json'},app.defaultConfig.configPath);
            else
               [filename,path] =  utils.save_file({'*.json'}, app.lastConfigPath);
            end

            if ischar(filename) && ischar(path)
                fullpath = fullfile(path, filename);
                % save for next click
                app.lastConfigPath = path;
                % save to json
                jsonStr = jsonencode(userConfig,'PrettyPrint',true);
                fid = fopen(fullpath, 'w');
                fprintf(fid, jsonStr);
                fclose(fid);
                % log
                disp(['Config Saved to File: ',fullpath]);

                % change config file in GUI
                app.ConfigurationFileEditField.Value = filename;
            end

        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
 
            % read raw image
            if isempty(app.lastStructureImagepath)
                [filename,path]= utils.select_file({'*.tif'});
            else
                [filename,path]= utils.select_file({'*.tif'},app.lastStructureImagepath);
                
            end

            % process structure image
            if filename ~= 0 
                % create progress dialog
                d = uiprogressdlg(app.RoiImagingModuleUIFigure,'Title','Loading Image',...
                    'Indeterminate','on');
                drawnow

                % code
                app.process_structure_image(filename,path);
                
                % close the dialog box
                close(d);

                % 保存选择的路径，方便下次再点击
                app.lastStructureImagepath = path;
            end
        end

        % Button down function: RightPanel
        function RightPanelButtonDown(app, event)

        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;
            switch value
                case 'CH1'
                    img = app.imgAvgCh1;
                case 'CH2'
                    img = app.imgAvgCh2;
            end
            tempImg=mat2gray(img); %将im归一化到[0 1]区间内，且im中的最大值和最小值赋值为1和0
            tempImg=im2uint8(tempImg); %将im扩充到[0 255]
            app.Image.ImageSource = repmat(tempImg,1,1,3);
        end

        % Menu selected function: PowerCaculateMenu
        function PowerCaculateMenuSelected(app, event)
            app.PowerCaculateAPP = addons.Power_Caculate();
        end

        % Value changed function: ImagingPowerEditField
        function ImagingPowerEditFieldValueChanged(app, event)
            app.caculate_power();

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            if isa(app.hSI,'scanimage.SI')   
                app.get_scanimage_power();
                % 更新完功率后自动更新
                app.caculate_power();
            else
                uialert(app.RoiImagingModuleUIFigure,"Please Start Scanimage First",'Warning','Icon','warning');
            end


        end

        % Value changed function: ScanimageButton
        function ScanimageButtonValueChanged(app, event)
            value = app.ScanimageButton.Value;
            if value    
                try
                    app.hSI = evalin('base', 'hSI');
                    app.hSICtl = evalin('base', 'hSICtl');
                catch
                    uialert(app.RoiImagingModuleUIFigure,"Please Start Scanimage First", ...
                        'Warning','Icon','warning');
                    return;
                end
                
                % listen to power change in scanimage（没有用
                %app.powerListener = addlistener(app.hSI.hBeams,'powerFractions','PostSet',@app.update_scanimage_power);


                app.ScanimageButton.BackgroundColor = [0.33,0.60,0.85];

                app.CaculateButtonPushed();

            else
                app.hSI = scanimage.SI.empty;
                app.hSICtl = scanimage.SIController.empty;
                app.ScanimageButton.BackgroundColor = [1.00,0.00,0.00];
            end
        end

        % Selection changed function: StructureImagingButtonGroup
        function StructureImagingButtonGroupSelectionChanged(app, event)
            selectedButton = app.StructureImagingButtonGroup.SelectedObject;
            
            if selectedButton.Text == "Keep on"
                app.laser_keep_on();


            else
                %app.laser_1on_9off();

            end

        end

        % Value changed function: ROIdilateSpinner
        function ROIdilateSpinnerValueChanged(app, event)
            % dialte mask
            SE = strel('disk',app.ROIdilateSpinner.Value);
            app.roiMask = imdilate(app.roiMaskRaw ,SE);


            % display mask
            imshow(app.roiMask,'parent',app.UIAxes,'border','tight','initialmagnification','fit');
            axis(app.UIAxes, 'tight');


            % caculate ROI ratio
            roiRatio = round(length(find(app.roiMask>0))/numel(app.roiMask),4);
            app.RoiProportionLabel.Text = ['ROI Ratio: ','',num2str(roiRatio)];
            app.ROIRatioEditField.Value = roiRatio;
            
            % caculate ROI Power
            app.caculate_power();
            
            % active low logic: 1（white) to 0, 0（black）to 1
            app.roiMask = utils.active_low_logic(app.roiMask);

        end

        % Button pushed function: LoadImageButton_2
        function LoadImageButton_2Pushed(app, event)
            app.LoadImageButton_2.Enable = 'off';
            app.LoadImageButton_2.FontColor = [1.00,1.00,1.00];
            app.LoadImageButton_2.BackgroundColor = [0.96,0.65,0.11];
            [filename,path] = utils.select_file({'*.tif','*.png'},app.last_tiff_path);

            if filename ~= 0 % 如果不选择文件返回为0
                % save path for next click
                app.last_tiff_path = path;


                % load tiff
                app.image_showing= imread(fullfile(app.last_tiff_path,filename));
                app.imshow_handles = imshow(app.image_showing,[],'parent',app.UIAxes,'border','tight','initialmagnification','fit');

                axis(app.UIAxes, 'tight');
                hold(app.UIAxes,'on');
                
                % 直接先建立一个空图层
                app.DrawROI.mask_size = size(app.image_showing);
                app.DrawROI.mask = zeros(app.DrawROI.mask_size);
                app.DrawROI.colored_mask = zeros([app.DrawROI.mask_size,3]);
                app.DrawROI.mask_layer = imshow(app.DrawROI.colored_mask,[0,255],'parent',app.UIAxes,'border','tight','initialmagnification','fit');
                app.DrawROI.mask_layer.AlphaData = 0;
                app.DrawROI.outline_layer = imshow(zeros([app.DrawROI.mask_size,3]),[0,255],'parent',app.UIAxes,'border','tight','initialmagnification','fit');
                app.DrawROI.outline_layer.AlphaData = 0;
                % enable draw roi
                app.MaskOnCheckBox.Value = true;
                app.DrawROI.flag  = true;

            end
            app.LoadImageButton_2.Enable = 'on';
            app.LoadImageButton_2.FontColor = [0,0,0];
            app.LoadImageButton_2.BackgroundColor = [0.96,0.96,0.96];

        end

        % Window button down function: RoiImagingModuleUIFigure
        function RoiImagingModuleUIFigureWindowButtonDown(app, event)


            % get mouse position in UIaxes
            currentPosition =app.UIAxes.CurrentPoint;
            x = currentPosition(1,1);
            y = currentPosition(1,2);
            if x >= app.UIAxes.XLim(1) && x <= app.UIAxes.XLim(2) && y >= app.UIAxes.YLim(1) && y <= app.UIAxes.YLim(2)

                switch app.RoiImagingModuleUIFigure.SelectionType
                    % left click
                    case 'normal'
                        if app.draw.status == "handroi_drawing"
                            cancel_handroi();
                        end
                        pan_click();   

                        % Enabling conditions：already drawed roi manualy or run segmentation
                        if app.DrawROI.flag && ~isempty(app.DrawROI.mask)
                            % Click on ROI to make it white
                            app.DrawROI.select_cell(x,y);
                        end

                    % right click /ctrl + left click
                    case 'alt' 
                        % ctrl + left click
                        if app.RoiImagingModuleUIFigure.UserData.CtrlPressed 
                            disp("ctrl + left click");
                            app.DrawROI.delete_cell(x,y);
                        % right click
                        else 
                            handroi_start(x,y) % 手动圈选ROI 绘制起点
                            disp("right click");
                        end
                    
                    % shift click / left click+ right click
                    case 'extend' 
                        return
                end
            end

            
            function handroi_start(x,y)
                % 手动圈选ROI 绘制起点
                if app.draw.status ~= "handroi_drawing" && app.DrawROI.flag
                    % start draw flag set true
                    app.draw.status = "handroi_drawing";

                    app.DrawROI.start_position = [x,y];
                    app.DrawROI.last_position = [x,y];
                    app.DrawROI.current_stroke = [app.DrawROI.current_stroke;app.DrawROI.start_position];

                    % draw a red circle at the starting point.
                    app.DrawROI.plot_current_handle = plot(app.UIAxes,x,y, 'ro', 'MarkerSize', 8);
                    app.DrawROI.plot_handles = [app.DrawROI.plot_handles, app.DrawROI.plot_current_handle];
                    app.DrawROI.start_drawing = true;
                end

            end

            function cancel_handroi()
                % 取消手动圈选
                app.DrawROI.current_stroke = [];
                for i = 1:length(app.DrawROI.plot_handles)
                    delete(app.DrawROI.plot_handles(i));
                end
                app.DrawROI.plot_handles = [];
                app.draw.status = "idle";

            end

            function pan_click()
                app.draw.status = "axes_paning";
                app.draw.previous_point = app.UIAxes.CurrentPoint;


            end

        end

        % Window button up function: RoiImagingModuleUIFigure
        function RoiImagingModuleUIFigureWindowButtonUp(app, event)
            switch app.draw.status
                case "axes_paning"
                    app.draw.status = "idle";
                case "handroi_drawing"
                    return
                otherwise
                    return
            end
        end

        % Window button motion function: RoiImagingModuleUIFigure
        function RoiImagingModuleUIFigureWindowButtonMotion(app, event)
            % 鼠标移动过程中的绘图
            currentPosition =app.UIAxes.CurrentPoint;
            x = currentPosition(1,1);
            y = currentPosition(1,2);
            if x >= app.UIAxes.XLim(1) && x <= app.UIAxes.XLim(2) && y >= app.UIAxes.YLim(1) && y <= app.UIAxes.YLim(2)
                switch  app.draw.status
                    case "axes_paning"
                        pan_motion()
                    case "handroi_drawing"
                        handroi_motion(x,y)
                    case "correcting_roi"
                        handroi_motion(x,y)
                    otherwise
                        return 
                end
            end


            function handroi_motion(x,y)

                if ~app.DrawROI.start_drawing
                    return
                end
                if ~is_at_start()
                    % draw stroke
                    app.DrawROI.plot_current_handle = plot(app.UIAxes,[app.DrawROI.last_position(1), x], [app.DrawROI.last_position(2), y],Color='r',LineWidth=3);  % 绘制红色线段
                    app.DrawROI.plot_handles = [app.DrawROI.plot_handles, app.DrawROI.plot_current_handle];
                    app.DrawROI.last_position = [x, y];
                    %axis([1,512,1,512]);

                    app.DrawROI.current_stroke = [app.DrawROI.current_stroke;[x,y]];
                else
                    % end stroke
                    app.draw.status = "idle";

                    app.DrawROI.last_position = zeros(1,2);
                    app.DrawROI.start_position = [];
                    app.DrawROI.start_drawing = false;

                    app.DrawROI.n_ROI = app.DrawROI.n_ROI + 1;

                    for i = 1:length(app.DrawROI.plot_handles)
                        delete(app.DrawROI.plot_handles(i));
                    end


                    app.DrawROI.plot_handles = [];

                    % stroke point to roi area
                    new_mask = components.drawRoi.stroke_to_mask(app.DrawROI.current_stroke,app.DrawROI.mask_size);
                    app.DrawROI.strokes{app.DrawROI.n_ROI} = app.DrawROI.current_stroke;
                    app.DrawROI.current_stroke = [];

                    % exclude existed roi area
                    if isempty(app.DrawROI.mask)
                        app.DrawROI.mask = zeros(app.DrawROI.mask_size);
                        app.DrawROI.colored_mask = zeros([app.DrawROI.mask_size,3]);
                    end
                    new_roi_position =(app.DrawROI.mask==0) .* (new_mask==1); % extract position to new
                    new_roi_position = logical(new_roi_position); % to logical array
                    % add roi to seg_mask
                    app.ROIsEditField.Value = app.ROIsEditField.Value + 1;
                    app.DrawROI.mask(new_roi_position) = app.ROIsEditField.Value; % add new roi

                    % add new roi area to previous mask
                    app.DrawROI.colored_mask = components.drawRoi.rgb_add_area(app.DrawROI.colored_mask,new_roi_position,app.DrawROI.colormaps);
                    
                    
                    % Update mask layer
                    app.DrawROI.update_mask_layer();

                    % caculate new roi ratio
                    roiRatio = round(length(find(app.DrawROI.mask>0))/numel(app.DrawROI.mask),4);
                    app.ROIRatioEditField_2.Value = roiRatio;
                    app.ROIRatioEditField.Value = roiRatio;

                end
            end


            function pan_motion()
                % get mouse position in UIaxes
                current_position =app.UIAxes.CurrentPoint;

                % get current location (in pixels)
                % get current XY-limits
                xlim_range = get(app.UIAxes, 'xlim');
                ylim_range = get(app.UIAxes, 'ylim');
                % find change in position
                delta_points = current_position - app.draw.previous_point;

                % Adjust limits
                set(app.UIAxes, 'Xlim', xlim_range - delta_points(1));
                set(app.UIAxes, 'Ylim', ylim_range - delta_points(3));

                % save new position
                app.draw.previous_point = get(app.UIAxes, 'CurrentPoint');

            end

            function result = is_at_start()
                % 需要设置至少距离大于多少后，才去判断是否靠近起始点，否则一开始画就end stroke了
                thresh_out = max(6,app.DrawROI.brush_size*3); %max(6,app.brush_size*3);
                thresh_in = 4; %max(3,app.brush_size*1.8);
                if length(app.DrawROI.current_stroke)>3 % 至少要有4个点，才去判断

                    dist = sqrt(sum((app.DrawROI.current_stroke(1,:)-app.DrawROI.current_stroke(2:end,:)).^2, 2));
                    dist = dist(:);
                    has_left = find(dist > thresh_out);
                    if ~isempty(has_left)
                        first_left = min(has_left);
                        has_returned = sum(dist(max(4, first_left+1):end) < thresh_in); % 
                        if has_returned > 0
                            result = true;
                        else
                            result = false;
                        end
                    else
                        result = false;
                    end
                else
                    result =false;
                end
            end
        
        end

        % Window scroll wheel function: RoiImagingModuleUIFigure
        function RoiImagingModuleUIFigureWindowScrollWheel(app, event)
            % 鼠标滚轮放大

            currentPosition =app.UIAxes.CurrentPoint;
            x = currentPosition(1,1);
            y = currentPosition(1,2);
            if x >= app.UIAxes.XLim(1) && x <= app.UIAxes.XLim(2) && y >= app.UIAxes.YLim(1) && y <= app.UIAxes.YLim(2)
                if event.VerticalScrollCount > 0
                    scale = 1.1;
                else
                    scale = 1/1.1;
                end
                xlim_range = get(app.UIAxes, 'xlim');
                ylim_range = get(app.UIAxes, 'ylim');
                app.UIAxes.XLim = (xlim_range - x) * scale + x;
                app.UIAxes.YLim = (ylim_range - y) * scale + y;
            end
        end

        % Window key press function: RoiImagingModuleUIFigure
        function RoiImagingModuleUIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'control'
                    disp("Control pressed")
                    app.RoiImagingModuleUIFigure.UserData.CtrlPressed = true;
                case 'uparrow'
                    move_mask(-1,0);
                case 'downarrow'
                   move_mask(1,0);
                case 'leftarrow'
                    move_mask(0,-1);
                case 'rightarrow'
                    move_mask(0,1);
                otherwise
                    return
            end


            function move_mask(down,right)
                mask = zeros(512,512,3);
                positon = zeros(512,512);


                % mask(down+1:end,right+1:end,:) = (1:end-down,1:end-right,:);
                app.DrawROI.colored_mask = circshift(app.DrawROI.colored_mask,[down,right,0]);

                app.DrawROI.binary_mask = circshift(app.DrawROI.binary_mask,[down,right]);

                % positon(down+1:end,right+1:end) = app.DrawROI.binary_mask(1:end-down,1:end-right);
                % app.DrawROI.binary_mask = positon;

                app.DrawROI.mask_layer.CData = app.DrawROI.colored_mask;
                app.DrawROI.mask_layer.AlphaData=app.DrawROI.mask_alphadata;
            end
        end

        % Window key release function: RoiImagingModuleUIFigure
        function RoiImagingModuleUIFigureWindowKeyRelease(app, event)
            key = event.Key;
            switch key
                case 'escape'
                    % cancel draw roi manually
                    app.DrawROI.current_stroke = [];
                    for i = 1:length(app.DrawROI.plot_handles)
                        delete(app.DrawROI.plot_handles(i));
                    end
                    app.DrawROI.plot_handles = [];
                    app.draw.status = "idle";
                case 'delete'
                    if app.DrawROI.last_selected_roi_index
                       app.DrawROI.delete_selected_cell();
                    end
                case 'control'
                    app.RoiImagingModuleUIFigure.UserData.CtrlPressed = false;
                otherwise
                    return
            end
        end

        % Key press function: RoiImagingModuleUIFigure
        function RoiImagingModuleUIFigureKeyPress(app, event)

        end

        % Key release function: RoiImagingModuleUIFigure
        function RoiImagingModuleUIFigureKeyRelease(app, event)

        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            % process bar
            progressDlg = uiprogressdlg(app.RoiImagingModuleUIFigure,'Title','Running neuron segmentation',...
                'Indeterminate','on');
            drawnow

            
            model_type = app.ModelsDropDown.Value;
            flow_threshold = app.ThresholdSpinner.Value;
            app.Segmentation = components.Segmentation(app.image_showing,model_type,flow_threshold);
            % [app.Segmentation.seg_mask, app.Segmentation.seg_flow, app.DrawROI.mask] = app.Segmentation.run();
            app.DrawROI.mask = app.Segmentation.run();
            % close the dialog box
            close(progressDlg);

            % caculate roi info
            app.ROIsEditField.Value = double(max(app.DrawROI.mask(:)));
            roiRatio = round(length(find(app.DrawROI.mask>0))/numel(app.DrawROI.mask),4);
            app.ROIRatioEditField_2.Value = roiRatio;
            app.ROIRatioEditField.Value = roiRatio;
            
            % add colored mask to gui

            app.DrawROI.colored_mask = components.drawRoi.mask_to_rgb(app.DrawROI.mask,app.DrawROI.colormaps);

            % mask to gui
            if ~isempty(app.DrawROI.mask_layer)
                % update
                app.DrawROI.mask_layer.CData = app.DrawROI.colored_mask;
                app.DrawROI.mask_layer.AlphaData = app.DrawROI.mask_alphadata;
            else
                % create mask layer
                hold(app.UIAxes,"on");
                app.DrawROI.mask_layer = imshow(app.DrawROI.colored_mask,[],'parent',app.UIAxes,'border','tight','initialmagnification','fit');
                app.DrawROI.mask_layer.AlphaData=app.DrawROI.mask_alphadata;
            end


            app.Segmentation.adjust_threshold = true; % 支持调整threshold，就自动显示
            
            % toggle mask style button to colored mask
            app.MaskDropDown.Value = "Colored";


        end

        % Value changed function: ThresholdSpinner
        function ThresholdSpinnerValueChanged(app, event)
            value = app.ThresholdSpinner.Value;
            if  isempty(app.DrawROI.mask) || ~app.Segmentation.adjust_threshold
                return 
            end
            
            new_mask = app.Segmentation.change_threshold(value);
            % 为了已存在的roi不更改颜色，只在mask上新增新的roi和删除roi，需要找出new mask和old mask的不同
            result = logical(new_mask)-logical(app.Segmentation.seg_mask_matlab);
            add_area = result>0;
            delete_area = result<0;

            %% Delete roi first
            if delete_area
                % 1、获取要删除的编号和位置
                % 2、删除指定ROI
                % 3、对seg mask进行重新排序,更新app.Segmentation.seg_mask_matlab以及app.DrawROI.mask
                % 4、重新绘图
                %获取要删除的编号
                delete_indexes = unique(app.Segmentation.seg_mask_matlab(delete_area),'sort');
                for i = 1:length(delete_indexes)
                    % 获取要删除的roi的位置
                    roi_position = app.Segmentation.seg_mask_matlab==delete_indexes(i);
                    roi_position_3D = repmat(roi_position,1,1,3);
                    % 在 mask 里删除 roi
                    app.Segmentation.seg_mask_matlab(roi_position) =0;
                    app.DrawROI.colored_mask(roi_position_3D) = 0;
                end
                % 由于删除后，需要对seg mask进行重新排序
                app.Segmentation.seg_mask_matlab = components.drawRoi.mask_reorder(app.Segmentation.seg_mask_matlab);

                % % % 计算删除后的ROI数目
                n_roi = max(app.Segmentation.seg_mask_matlab,[],'all');
            end
            
            %% Add roi
            if add_area
                % 获取新增roi的位置
                add_indexes = unique(new_mask(add_area),'sort');
                for i = 1:length(add_indexes)
                    % 获取要增加的roi的位置
                    new_roi_position = new_mask==add_indexes(i);
                    % add roi to seg_mask
                    n_roi = n_roi + 1;
                    app.Segmentation.seg_mask_matlab(new_roi_position) = n_roi; % add new roi
                    % add new roi area to previous mask
                    app.DrawROI.colored_mask = components.drawRoi.rgb_add_area(app.DrawROI.colored_mask,new_roi_position,app.DrawROI.colormaps);

                end
            end
            %% Redraw in mask layer
            app.DrawROI.mask = app.Segmentation.seg_mask_matlab;
            app.DrawROI.mask_layer.CData = app.DrawROI.colored_mask;
            app.DrawROI.mask_layer.AlphaData = app.DrawROI.mask_alphadata;


            %% Caculate roi info
            app.ROIsEditField.Value = max(double(app.DrawROI.mask),[],'all');
            roiRatio = round(length(find(app.DrawROI.mask>0))/numel(app.DrawROI.mask),4);
            app.ROIRatioEditField_2.Value = roiRatio;
            app.ROIRatioEditField.Value = roiRatio;
        end

        % Value changed function: ModelsDropDown
        function ModelsDropDownValueChanged(app, event)
            value = app.ModelsDropDown.Value;
            app.Segmentation.adjust_threshold = false;

        end

        % Value changed function: MaskDropDown
        function MaskDropDownValueChanged(app, event)
            app.DrawROI.update_mask_layer();
        end

        % Value changed function: MaskOnCheckBox
        function MaskOnCheckBoxValueChanged(app, event)
            value = app.MaskOnCheckBox.Value;
            if isempty(app.DrawROI.mask_layer)
                return
            end
            if value
                % disable draw roi
                app.DrawROI.flag  = true;
                app.DrawROI.update_mask_layer();
            else
                % enable draw roi
                app.DrawROI.flag  = false;
                app.DrawROI.mask_layer.AlphaData = 0;
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RoiImagingModuleUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {677, 677};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {275, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RoiImagingModuleUIFigure = uifigure('Visible', 'off');
            app.RoiImagingModuleUIFigure.Name = 'ROI Imaging Module';
            app.RoiImagingModuleUIFigure.CloseRequestFcn = createCallbackFcn(app, @RoiImagingModuleUIFigureCloseRequest, true);
            app.RoiImagingModuleUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.RoiImagingModuleUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @RoiImagingModuleUIFigureWindowButtonDown, true);
            app.RoiImagingModuleUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @RoiImagingModuleUIFigureWindowButtonUp, true);
            app.RoiImagingModuleUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @RoiImagingModuleUIFigureWindowButtonMotion, true);
            app.RoiImagingModuleUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @RoiImagingModuleUIFigureWindowScrollWheel, true);
            app.RoiImagingModuleUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @RoiImagingModuleUIFigureWindowKeyPress, true);
            app.RoiImagingModuleUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @RoiImagingModuleUIFigureWindowKeyRelease, true);
            app.RoiImagingModuleUIFigure.KeyPressFcn = createCallbackFcn(app, @RoiImagingModuleUIFigureKeyPress, true);
            app.RoiImagingModuleUIFigure.KeyReleaseFcn = createCallbackFcn(app, @RoiImagingModuleUIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.RoiImagingModuleUIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = ' File ';
            app.SaveConfigMenu = uimenu(app.FileMenu);
            app.SaveConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveConfigMenuSelected, true);
            app.SaveConfigMenu.Text = 'Save Config';
            app.SettingsMenu = uimenu(app.RoiImagingModuleUIFigure);
            app.SettingsMenu.Text = ' Settings ';
            app.AWGSettingsMenu = uimenu(app.SettingsMenu);
            app.AWGSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @AWGSettingsMenuSelected, true);
            app.AWGSettingsMenu.Text = 'AWG Settings';
            app.ScannerSettingsMenu = uimenu(app.SettingsMenu);
            app.ScannerSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @ScannerSettingsMenuSelected, true);
            app.ScannerSettingsMenu.Text = 'Scanner Settings';
            app.AddonsMenu = uimenu(app.RoiImagingModuleUIFigure);
            app.AddonsMenu.Text = ' Add-ons ';
            app.PowerCaculateMenu = uimenu(app.AddonsMenu);
            app.PowerCaculateMenu.MenuSelectedFcn = createCallbackFcn(app, @PowerCaculateMenuSelected, true);
            app.PowerCaculateMenu.Text = 'Power Caculate';
            app.AWGControlMenu = uimenu(app.AddonsMenu);
            app.AWGControlMenu.MenuSelectedFcn = createCallbackFcn(app, @AWGControlMenuSelected, true);
            app.AWGControlMenu.Text = 'AWG Control';
            app.ROIImagingSimulationMenu = uimenu(app.AddonsMenu);
            app.ROIImagingSimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIImagingSimulationMenuSelected, true);
            app.ROIImagingSimulationMenu.Text = 'ROI Imaging Simulation';
            app.CustomDrawMenu_2 = uimenu(app.AddonsMenu);
            app.CustomDrawMenu_2.Text = 'Custom Draw';
            app.HelpMenu = uimenu(app.RoiImagingModuleUIFigure);
            app.HelpMenu.Text = ' Help ';
            app.Toolbar = uitoolbar(app.RoiImagingModuleUIFigure);
            app.SimulationToggleTool = uitoggletool(app.Toolbar);
            app.SimulationToggleTool.Icon = fullfile(pathToMLAPP, 'assets', 'icon', 'simulation.svg');
            app.GridLayout = uigridlayout(app.RoiImagingModuleUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StructureImagingButtonGroup = uibuttongroup(app.LeftPanel);
            app.StructureImagingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StructureImagingButtonGroupSelectionChanged, true);
            app.StructureImagingButtonGroup.Title = '1. Structure Imaging';
            app.KeeponButton = uiradiobutton(app.StructureImagingButtonGroup);
            app.KeeponButton.Text = 'Keep on';
            app.KeeponButton.Value = true;
            app.oneOnNineOffButton = uiradiobutton(app.StructureImagingButtonGroup);
            app.oneOnNineOffButton.Text = '0.1MHz';
            app.LoadImageButton = uibutton(app.StructureImagingButtonGroup, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load  Image';
            app.AWGOutputButton = uibutton(app.StructureImagingButtonGroup, 'push');
            app.AWGOutputButton.ButtonPushedFcn = createCallbackFcn(app, @AWGOutputButtonPushed, true);
            app.AWGOutputButton.Text = 'AWG Output';
            app.StructureImagingLamp = uilamp(app.StructureImagingButtonGroup);
            app.NeuronSegmentationPanel = uipanel(app.LeftPanel);
            app.NeuronSegmentationPanel.Title = '2. Neuron Segmentation';
            app.ModelsDropDownLabel = uilabel(app.NeuronSegmentationPanel);
            app.ModelsDropDownLabel.Text = 'Models';
            app.ModelsDropDown = uidropdown(app.NeuronSegmentationPanel);
            app.ModelsDropDown.Items = {'cyto2', 'cyto', 'CP'};
            app.ModelsDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelsDropDownValueChanged, true);
            app.ModelsDropDown.Value = 'cyto2';
            app.ThresholdSpinnerLabel = uilabel(app.NeuronSegmentationPanel);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.NeuronSegmentationPanel);
            app.ThresholdSpinner.Step = 0.05;
            app.ThresholdSpinner.LowerLimitInclusive = 'off';
            app.ThresholdSpinner.Limits = [0 3];
            app.ThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ThresholdSpinnerValueChanged, true);
            app.ThresholdSpinner.Tooltip = {'set  higher to get more cells, in range from (0,3]'};
            app.ThresholdSpinner.Value = 0.1;
            app.RunModelButton = uibutton(app.NeuronSegmentationPanel, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = 'Run Model';
            app.LoadImageButton_2 = uibutton(app.NeuronSegmentationPanel, 'push');
            app.LoadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButton_2Pushed, true);
            app.LoadImageButton_2.Text = 'Load Image';
            app.ManualcorrectionPanel = uipanel(app.LeftPanel);
            app.ManualcorrectionPanel.Title = '3. Manual correction';
            app.MaskDropDownLabel = uilabel(app.ManualcorrectionPanel);
            app.MaskDropDownLabel.Text = 'Mask';
            app.MaskDropDown = uidropdown(app.ManualcorrectionPanel);
            app.MaskDropDown.Items = {'Colored', 'Binary'};
            app.MaskDropDown.ValueChangedFcn = createCallbackFcn(app, @MaskDropDownValueChanged, true);
            app.MaskDropDown.Value = 'Colored';
            app.MaskOnCheckBox = uicheckbox(app.ManualcorrectionPanel);
            app.MaskOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaskOnCheckBoxValueChanged, true);
            app.MaskOnCheckBox.Text = '';
            app.ROIdilateSpinnerLabel = uilabel(app.ManualcorrectionPanel);
            app.ROIdilateSpinnerLabel.Text = 'ROI  dilate';
            app.ROIdilateSpinner = uispinner(app.ManualcorrectionPanel);
            app.ROIdilateSpinner.Limits = [0 Inf];
            app.ROIdilateSpinner.ValueChangedFcn = createCallbackFcn(app, @ROIdilateSpinnerValueChanged, true);
            app.ROIdilateSpinner.Value = 1;
            app.LoadMaskButton = uibutton(app.ManualcorrectionPanel, 'push');
            app.LoadMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMaskButtonPushed, true);
            app.LoadMaskButton.Text = 'Load  Mask';
            app.ROIImagingPanel = uipanel(app.LeftPanel);
            app.ROIImagingPanel.Title = '4. ROI Imaging';
            app.ROIImagingButton = uibutton(app.ROIImagingPanel, 'push');
            app.ROIImagingButton.ButtonPushedFcn = createCallbackFcn(app, @ROIImagingButtonPushed, true);
            app.ROIImagingButton.Text = 'ROI Imaging';
            app.AbortButton = uibutton(app.ROIImagingPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.ROIImagingLamp = uilamp(app.ROIImagingPanel);
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.AwgConnectButton = uibutton(app.SettingsPanel, 'push');
            app.AwgConnectButton.ButtonPushedFcn = createCallbackFcn(app, @AwgConnectButtonPushed, true);
            app.AwgConnectButton.Text = 'Connect';
            app.AWGStatusLabel = uilabel(app.SettingsPanel);
            app.AWGStatusLabel.Text = 'AWG Status';
            app.isConnectedLabel = uilabel(app.SettingsPanel);
            app.isConnectedLabel.Text = 'Disconnected';
            app.ConfigFileSelectButton = uibutton(app.SettingsPanel, 'push');
            app.ConfigFileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigFileSelectButtonPushed, true);
            app.ConfigFileSelectButton.Text = '...';
            app.AdvancedSettingsOpenButton = uibutton(app.SettingsPanel, 'push');
            app.AdvancedSettingsOpenButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedSettingsOpenButtonPushed, true);
            app.AdvancedSettingsOpenButton.Text = 'Advanced';
            app.ConfigurationEditFieldLabel = uilabel(app.SettingsPanel);
            app.ConfigurationEditFieldLabel.Text = 'Configuration';
            app.ConfigurationFileEditField = uieditfield(app.SettingsPanel, 'text');
            app.ScanimageButton = uibutton(app.SettingsPanel, 'state');
            app.ScanimageButton.ValueChangedFcn = createCallbackFcn(app, @ScanimageButtonValueChanged, true);
            app.ScanimageButton.Icon = fullfile(pathToMLAPP, 'assets', 'icon', 'ScanImage.png');
            app.ScanimageButton.Text = '';
            app.ScanimageButton.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.ButtonDownFcn = createCallbackFcn(app, @RightPanelButtonDown, true);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ChannelDropDown = uidropdown(app.RightPanel);
            app.ChannelDropDown.Items = {'CH1', 'CH2'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 'CH1';
            app.PowerCaculatePanel = uipanel(app.RightPanel);
            app.PowerCaculatePanel.Title = 'Power Caculate';
            app.LaserPowermWLabel = uilabel(app.PowerCaculatePanel);
            app.LaserPowermWLabel.Text = 'Laser  Power(mW)';
            app.ImagingPowerEditField = uieditfield(app.PowerCaculatePanel, 'numeric');
            app.ImagingPowerEditField.ValueChangedFcn = createCallbackFcn(app, @ImagingPowerEditFieldValueChanged, true);
            app.MHzPowermWEditFieldLabel = uilabel(app.PowerCaculatePanel);
            app.MHzPowermWEditFieldLabel.Text = '0.1MHz Power(mW)';
            app.StructurePowerEditField = uieditfield(app.PowerCaculatePanel, 'numeric');
            app.ROIRatioEditFieldLabel = uilabel(app.PowerCaculatePanel);
            app.ROIRatioEditFieldLabel.Text = 'ROI Ratio';
            app.ROIRatioEditField = uieditfield(app.PowerCaculatePanel, 'numeric');
            app.ROIRatioEditField.Editable = 'off';
            app.ROIPowermWEditField_2Label = uilabel(app.PowerCaculatePanel);
            app.ROIPowermWEditField_2Label.Text = 'ROI Power(mW)';
            app.ROIPowerEditField = uieditfield(app.PowerCaculatePanel, 'numeric');
            app.PowerCoseLabel = uilabel(app.PowerCaculatePanel);
            app.PowerCoseLabel.Text = 'Power Cost';
            app.PowerCostEditField = uieditfield(app.PowerCaculatePanel, 'numeric');
            app.PowerCostEditField.Value = 1;
            app.UpdateButton = uibutton(app.PowerCaculatePanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ROIsEditFieldLabel = uilabel(app.RightPanel);
            app.ROIsEditFieldLabel.Text = 'ROIs';
            app.ROIsEditField = uieditfield(app.RightPanel, 'numeric');
            app.ROIsEditField.Limits = [0 Inf];
            app.ROIsEditField.ValueDisplayFormat = '%.0f';
            app.ROIsEditField.Editable = 'off';
            app.ROIRatioEditField_2Label = uilabel(app.RightPanel);
            app.ROIRatioEditField_2Label.Text = 'ROI Ratio';
            app.ROIRatioEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.ROIRatioEditField_2.Limits = [0 Inf];
            app.ROIRatioEditField_2.ValueDisplayFormat = '%.3f';
            % Show the figure after all components are created
            app.RoiImagingModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoiImagingModule

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RoiImagingModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RoiImagingModuleUIFigure)
        end
    end
end